classdef interfaz1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EcualizadorUIFigure  matlab.ui.Figure
        Bajos1SliderLabel    matlab.ui.control.Label
        Bajos1Slider         matlab.ui.control.Slider
        Bajos2SliderLabel    matlab.ui.control.Label
        Bajos2Slider         matlab.ui.control.Slider
        Bajos3SliderLabel    matlab.ui.control.Label
        Bajos3Slider         matlab.ui.control.Slider
        MedioSliderLabel     matlab.ui.control.Label
        MedioSlider          matlab.ui.control.Slider
        Altos1SliderLabel    matlab.ui.control.Label
        Altos1Slider         matlab.ui.control.Slider
        Altos2SliderLabel    matlab.ui.control.Label
        Altos2Slider         matlab.ui.control.Slider
        Altos3SliderLabel    matlab.ui.control.Label
        Altos3Slider         matlab.ui.control.Slider
        BienvenidoLabel      matlab.ui.control.Label
        AleatorioButton      matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: AleatorioButton
        function AleatorioButtonPushed(app, event)
            vals = rand(1,7)*2;
            app.Bajos1Slider.Value = vals(1);
            Bajos1SliderValueChanged(app,event);
            app.Bajos2Slider.Value = vals(2);
            Bajos2SliderValueChanged(app,event);
            app.Bajos3Slider.Value = vals(3);
            Bajos3SliderValueChanged(app,event);
            app.MedioSlider.Value = vals(4);
            MedioSliderValueChanged(app,event);
            app.Altos1Slider.Value = vals(5);
            Altos1SliderValueChanged(app,event);
            app.Altos2Slider.Value = vals(6);
            Altos2SliderValueChanged(app,event);
            app.Altos3Slider.Value = vals(7);
            Altos3SliderValueChanged(app,event);
        end

        % Value changed function: Altos1Slider
        function Altos1SliderValueChanged(app, event)
            value = app.Altos1Slider.Value;
            ecualizador
            set_param('ecualizador/Gain4','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Altos2Slider
        function Altos2SliderValueChanged(app, event)
            value = app.Altos2Slider.Value;
            ecualizador
            set_param('ecualizador/Gain5','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Altos3Slider
        function Altos3SliderValueChanged(app, event)
            value = app.Altos3Slider.Value;
            ecualizador
            set_param('ecualizador/Gain6','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Bajos1Slider
        function Bajos1SliderValueChanged(app, event)
            value = app.Bajos1Slider.Value;
            ecualizador
            set_param('ecualizador/Gain','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Bajos2Slider
        function Bajos2SliderValueChanged(app, event)
            value = app.Bajos2Slider.Value;
            ecualizador
            set_param('ecualizador/Gain1','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Bajos3Slider
        function Bajos3SliderValueChanged(app, event)
            value = app.Bajos3Slider.Value;
            ecualizador
            set_param('ecualizador/Gain2','Gain',sprintf('%.6f',value))
        end

        % Value changed function: MedioSlider
        function MedioSliderValueChanged(app, event)
            value = app.MedioSlider.Value;
            ecualizador
            set_param('ecualizador/Gain3','Gain',sprintf('%.6f',value))
        end

        % Callback function
        function Slider7ValueChanging(app, event)
            value = event.Value;
            ecualizador
            set_param('ecualizador/Gain6','Gain',sprintf('%.6f',value))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EcualizadorUIFigure = uifigure;
            app.EcualizadorUIFigure.Name = 'Ecualizador';
            app.Bajos1SliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Bajos1SliderLabel.Text = 'Bajos 1';
            app.Bajos1Slider = uislider(app.EcualizadorUIFigure);
            app.Bajos1Slider.Limits = [0 2];
            app.Bajos1Slider.MajorTicks = [];
            app.Bajos1Slider.MajorTickLabels = {};
            app.Bajos1Slider.ValueChangedFcn = createCallbackFcn(app, @Bajos1SliderValueChanged, true);
            app.Bajos1Slider.MinorTicks = [];
            app.Bajos1Slider.Value = 1;
            app.Bajos2SliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Bajos2SliderLabel.Text = 'Bajos 2';
            app.Bajos2Slider = uislider(app.EcualizadorUIFigure);
            app.Bajos2Slider.Limits = [0 2];
            app.Bajos2Slider.MajorTicks = [];
            app.Bajos2Slider.MajorTickLabels = {};
            app.Bajos2Slider.ValueChangedFcn = createCallbackFcn(app, @Bajos2SliderValueChanged, true);
            app.Bajos2Slider.MinorTicks = [];
            app.Bajos2Slider.Value = 1;
            app.Bajos3SliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Bajos3SliderLabel.Text = 'Bajos 3';
            app.Bajos3Slider = uislider(app.EcualizadorUIFigure);
            app.Bajos3Slider.Limits = [0 2];
            app.Bajos3Slider.MajorTicks = [];
            app.Bajos3Slider.MajorTickLabels = {};
            app.Bajos3Slider.ValueChangedFcn = createCallbackFcn(app, @Bajos3SliderValueChanged, true);
            app.Bajos3Slider.MinorTicks = [];
            app.Bajos3Slider.Value = 1;
            app.MedioSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.MedioSliderLabel.Text = 'Medio';
            app.MedioSlider = uislider(app.EcualizadorUIFigure);
            app.MedioSlider.Limits = [0 2];
            app.MedioSlider.MajorTicks = [];
            app.MedioSlider.MajorTickLabels = {};
            app.MedioSlider.ValueChangedFcn = createCallbackFcn(app, @MedioSliderValueChanged, true);
            app.MedioSlider.MinorTicks = [];
            app.MedioSlider.Value = 1;
            app.Altos1SliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Altos1SliderLabel.Text = 'Altos 1';
            app.Altos1Slider = uislider(app.EcualizadorUIFigure);
            app.Altos1Slider.Limits = [0 2];
            app.Altos1Slider.MajorTicks = [];
            app.Altos1Slider.MajorTickLabels = {};
            app.Altos1Slider.ValueChangedFcn = createCallbackFcn(app, @Altos1SliderValueChanged, true);
            app.Altos1Slider.MinorTicks = [];
            app.Altos1Slider.Value = 1;
            app.Altos2SliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Altos2SliderLabel.Text = 'Altos 2';
            app.Altos2Slider = uislider(app.EcualizadorUIFigure);
            app.Altos2Slider.Limits = [0 2];
            app.Altos2Slider.MajorTicks = [];
            app.Altos2Slider.MajorTickLabels = {};
            app.Altos2Slider.ValueChangedFcn = createCallbackFcn(app, @Altos2SliderValueChanged, true);
            app.Altos2Slider.MinorTicks = [];
            app.Altos2Slider.Value = 1;
            app.Altos3SliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Altos3SliderLabel.Text = 'Altos 3';
            app.Altos3Slider = uislider(app.EcualizadorUIFigure);
            app.Altos3Slider.Limits = [0 2];
            app.Altos3Slider.MajorTicks = [];
            app.Altos3Slider.MajorTickLabels = {};
            app.Altos3Slider.ValueChangedFcn = createCallbackFcn(app, @Altos3SliderValueChanged, true);
            app.Altos3Slider.MinorTicks = [];
            app.Altos3Slider.Value = 1;
            app.BienvenidoLabel = uilabel(app.EcualizadorUIFigure);
            app.BienvenidoLabel.Text = '¡Bienvenido!';
            app.AleatorioButton = uibutton(app.EcualizadorUIFigure, 'push');
            app.AleatorioButton.ButtonPushedFcn = createCallbackFcn(app, @AleatorioButtonPushed, true);
            app.AleatorioButton.Text = 'Aleatorio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interfaz1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EcualizadorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EcualizadorUIFigure)
        end
    end
end